classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControladorespanelinferiorPanel  matlab.ui.container.Panel
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        ControladorespanelsuperiorPanel  matlab.ui.container.Panel
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        EfectosButtonGroup           matlab.ui.container.ButtonGroup
        NingunoButton_2              matlab.ui.control.RadioButton
        AmplificacionButton          matlab.ui.control.RadioButton
        ReverberacionButton          matlab.ui.control.RadioButton
        DistorsionButton             matlab.ui.control.RadioButton
        EcoButton                    matlab.ui.control.RadioButton
        RuidoButtonGroup             matlab.ui.container.ButtonGroup
        NingunoButton                matlab.ui.control.RadioButton
        RuidoSinusoidalButton        matlab.ui.control.RadioButton
        RuidoImpulsivoButton         matlab.ui.control.RadioButton
        RuidoUniformeButton          matlab.ui.control.RadioButton
        RuidoBlancoButton            matlab.ui.control.RadioButton
        Image                        matlab.ui.control.Image
        HISTOGRAMAButton             matlab.ui.control.Button
        GeneralsealsimplePanel       matlab.ui.container.Panel
        GenerarsealButton            matlab.ui.control.Button
        PeriodosEditField            matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel       matlab.ui.control.Label
        AmplitudEditField            matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel       matlab.ui.control.Label
        FsEditField_3                matlab.ui.control.NumericEditField
        FsEditField_3Label           matlab.ui.control.Label
        FcEditField                  matlab.ui.control.NumericEditField
        FcEditFieldLabel             matlab.ui.control.Label
        SealDropDown                 matlab.ui.control.DropDown
        SealDropDownLabel            matlab.ui.control.Label
        CargarSealPanel              matlab.ui.container.Panel
        CompresinEditField           matlab.ui.control.EditField
        CompresinEditFieldLabel      matlab.ui.control.Label
        DuracionEditField            matlab.ui.control.NumericEditField
        DuracionEditFieldLabel       matlab.ui.control.Label
        PotsonoraEditField           matlab.ui.control.NumericEditField
        PotsonoraEditFieldLabel      matlab.ui.control.Label
        PotenciaEditField            matlab.ui.control.NumericEditField
        PotenciaEditFieldLabel       matlab.ui.control.Label
        nmuestrasEditField           matlab.ui.control.NumericEditField
        nmuestrasEditFieldLabel      matlab.ui.control.Label
        FsEditField_2                matlab.ui.control.NumericEditField
        FsEditField_2Label           matlab.ui.control.Label
        NCanalesEditField            matlab.ui.control.NumericEditField
        NCanalesEditFieldLabel       matlab.ui.control.Label
        OpenFileButton               matlab.ui.control.Button
        ModificarsealPanel           matlab.ui.container.Panel
        InvertirsealCheckBox         matlab.ui.control.CheckBox
        CompresinExpansionEditField  matlab.ui.control.NumericEditField
        CompresinExpansionEditFieldLabel  matlab.ui.control.Label
        GrabacinPanel                matlab.ui.container.Panel
        HzLabel                      matlab.ui.control.Label
        FsEditField                  matlab.ui.control.NumericEditField
        FsEditFieldLabel             matlab.ui.control.Label
        BitsDropDown                 matlab.ui.control.DropDown
        BitsDropDownLabel            matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        GrabarButton                 matlab.ui.control.Button
        SealEditadaPanel             matlab.ui.container.Panel
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        SealCargadaPanel             matlab.ui.container.Panel
        VerenelpanelinferiorButton   matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        audioData  % Datos del archivo de audio
        audioDataMod
        fs         % Frecuencia de muestreo del archivo de audio
        player
        player2
        recorder   % Objeto audiorecorder para grabación
        frecuencia 
        senalGenerada % Objeto para la senal generada
        OriginalSignal % Objeto para almacenar la senal original
        OriginalTime    % Objeto para almacenar el tiempo original
        OriginalFrecuen % Objeto para almacenar la frecuencia origianl
    end

    
    methods (Access = private)
        function updatePlot(app)
                % Obtener los datos grabados hasta el momento
                Data = getaudiodata(app.recorder);
    
                % Calcular el tiempo correspondiente
                t = (0:length(Data)-1) / app.recorder.SampleRate;
    
                % Graficar en tiempo real en los UIAxes2_2
                plot(app.UIAxes3, t, Data);
                axis(app.UIAxes3, 'tight');
                title(app.UIAxes3, 'Grabación en Tiempo Real');
                xlabel(app.UIAxes3, 'Tiempo (s)');
                ylabel(app.UIAxes3, 'Amplitud');
                drawnow;  % Actualizar la gráfica inmediatamente
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: OpenFileButton
        function Cargar_Archivo(app, event)
           % Seleccionar archivo de audio
            [filename, filepath] = uigetfile({'*.wav;*.mp3', 'Audios (*.wav, *.mp3)'});
    
            % Verificar si el usuario seleccionó un archivo
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No se seleccionó ningún archivo', 'Error');
                return;  % Salir si no se seleccionó ningún archivo
            end
    
            % Cargar archivo de audio
            fullFileName = fullfile(filepath, filename);
            [app.audioData, app.fs] = audioread(fullFileName);  % Cargar los datos de audio y la frecuencia de muestreo
            
            % Si ya existe un reproductor, detenerlo antes de actualizar
            if exist('audioPlayer', 'var') && ~isempty(app.player) && isvalid(app.player)
                stop(app.player);
            end
            app.audioData = mean(app.audioData,2);

            % Crear un nuevo objeto de reproducción
            app.player = audioplayer(app.audioData, app.fs);
    
            % Obtener información del archivo
            info = audioinfo(fullFileName);
            numChannels = info.NumChannels;
            numSamples = info.TotalSamples;
            duration = info.Duration;
            
            % Obtener la extensión del archivo cargado
            [~, ~, ext] = fileparts(fullFileName);  % 'ext' contendrá la extensión del archivo (por ejemplo, '.mp3', '.wav')

            % Determinar el tipo de compresión basado en la extensión del archivo
            if strcmp(ext, '.mp3')
                compressionMethod = 'MP3';  % Compresión MP3
            elseif strcmp(ext, '.wav')
                compressionMethod = 'WAV';  % Compresión WAV
            else
                compressionMethod = 'Desconocido';  % En caso de que no sea ni MP3 ni WAV
            end

             % Calcular potencia
             power = sum(app.audioData.^2) / numSamples;
             power_scalar = mean(power(:));

             % Calcular presión sonora
             p_ref = 20e-6; % Presión de referencia en pascales (umbral auditivo)
             pressure = sqrt(power_scalar) * p_ref;


            app.CompresinEditField.Editable = false;
            app.DuracionEditField.Editable = false;
            app.FsEditField_2.Editable = false;
            app.PotsonoraEditField.Editable = false;
            app.nmuestrasEditField.Editable = false;
            app.PotenciaEditField.Editable = false;
            app.NCanalesEditField.Editable = false;

            app.NCanalesEditField.Value = double(numChannels);
            app.FsEditField_2.Value = double(app.fs);
            app.nmuestrasEditField.Value = double(numSamples);
            app.CompresinEditField.Value= compressionMethod;  
            app.DuracionEditField.Value = double(duration);
            app.PotenciaEditField.Value = double(power_scalar);
            app.PotsonoraEditField.Value = double(pressure);
            
    
            t = (0:length(app.audioData)-1) / app.fs;

            % Enviar los datos del archivo al Time Scope ya creado
            plot(app.UIAxes3, t, app.audioData);
            xlabel(app.UIAxes3, 'Tiempo (s)');
            ylabel(app.UIAxes3, 'Amplitud (V)');
            axis(app.UIAxes3, 'tight');

            % Calcula el espectrograma
            signal = app.audioData; % Convierte estéreo a mono

            window = hamming(1024); % Ventana de 512 puntos
            noverlap = 512; % Solapamiento entre ventanas
            nfft = 2048; % Número de puntos FFT
            [~, F, T, P] = spectrogram(signal, window, noverlap, nfft, app.fs);
            
            % Visualiza el espectrograma
            imagesc(app.UIAxes2, T, F, 10*log10(P)); % Muestra el espectrograma en dB
            colormap(app.UIAxes2);
            axis(app.UIAxes2, 'tight');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Frecuencia (Hz)');
            app.UIAxes2.YDir = "normal";

            % Almacena los datos originales
            app.OriginalSignal = app.audioData; % Señal de audio original
            app.OriginalTime = t;               % Vector de tiempo original
            app.OriginalFrecuen = app.fs;
        end

        % Button pushed function: Button_3
        function Guardar_Archivo(app, event)
            % Código para guardar un archivo de audio
            [filename, filepath] = uiputfile('*.wav', 'Audios');
            if isequal(filename, 0)
               disp('No se seleccionó ningún nombre para guardar el archivo');
            else
                fullFileName = fullfile(filepath, filename);
                audiowrite(fullFileName, app.OriginalSignal, app.OriginalFrecuen); % Guarda el archivo de audio
                disp('Archivo guardado exitosamente');
            end
        end

        % Button pushed function: Button_2
        function Reproducir(app, event)
            %Callback del botón de reproducir
            % Verificar si hay un archivo de audio cargado
            if isempty(app.OriginalSignal)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado para reproducir.', 'Error');
                return;
            end

            % Verificar si el objeto player ya existe y está pausado
            if ~isempty(app.player) && isa(app.player, 'audioplayer') && ~isplaying(app.player)
                resume(app.player);  % Retomar la reproducción desde donde se pausó
            else
                % Crear el reproductor y reproducir desde el inicio
                app.player = audioplayer(app.OriginalSignal, app.OriginalFrecuen);
                play(app.player);
            end

        end

        % Button pushed function: Button
        function Pausa(app, event)
            %Callback del botón de pausa
            if isempty(app.OriginalSignal)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado para reproducir.', 'Error');
                return;
            end
    
            if ~isempty(app.player) && isplaying(app.player)
                pause(app.player);  % Pausar la reproducción
            end       
        end

        % Button pushed function: Button_4
        function Stop(app, event)
            %Callback del botón de parar
            % Verificar si hay un archivo de audio cargado
            if isempty(app.OriginalSignal)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado para reproducir.', 'Error');
                return;
            end

            if ~isempty(app.player) && isplaying(app.player)
                stop(app.player);  % Detiene la reproducción
            end

            % Reiniciar el reproductor creando un nuevo objeto audioplayer
            app.player = audioplayer(app.OriginalSignal, app.OriginalFrecuen);
        end

        % Button pushed function: GrabarButton
        function Grabar(app, event)
            %Callback del boton grabar
            % Verificar si ya hay una grabación en curso
            if ~isempty(app.recorder) && isrecording(app.recorder)
                uialert(app.UIFigure, 'Ya hay una grabación en curso.', 'Error');
                return;
            end

            % Obtener parámetros de grabación
            fsGrabando = app.FsEditField.Value;  % Frecuencia de muestreo desde el campo de texto
            bits = str2double(app.BitsDropDown.Value);  % Bits desde el DropDown
    
            % Crear objeto audiorecorder
            app.recorder = audiorecorder(fsGrabando, bits, 1);  % fs: frecuencia, bits: profundidad de bits, 1 canal
            
            % Configurar TimerFcn para actualización en tiempo real
            app.recorder.TimerPeriod = 0.1;  % Intervalo de actualización en segundos
            app.recorder.TimerFcn = @(src, event) updatePlot(app);

            % Limpiar gráficas previas
            cla(app.UIAxes3);
            title(app.UIAxes3, 'Grabación en Proceso...');
            xlabel(app.UIAxes3, 'Tiempo (s)');
            ylabel(app.UIAxes3, 'Amplitud (V)');
            axis(app.UIAxes3, 'tight');

            % Iniciar grabación
            record(app.recorder);


        end

        % Button pushed function: StopButton
        function Parar_Grabacion(app, event)
            %Callback del botón de stop grabación

            % Verificar si hay una grabación en curso
            if isempty(app.recorder) || ~isrecording(app.recorder)
                uialert(app.UIFigure, 'No hay ninguna grabación en curso.', 'Error');
                return;
            end
    
            % Detener la grabación
            stop(app.recorder);
    
            % Obtener los datos grabados
            app.audioData = getaudiodata(app.recorder);
            app.fs = app.recorder.SampleRate;
    
            % Calcular el tiempo
            t = (0:length(app.audioData)-1) / app.fs;  

            % Almacenamos los datos de la señal
            app.OriginalSignal = app.audioData; % Señal de audio grabada
            app.OriginalTime = t;               % Vector de tiempo grabado    
    
            % Mostrar en UIAxes del Panel 2
            plot(app.UIAxes3, t, app.audioData);
            title(app.UIAxes3, 'Audio Grabado');
            xlabel(app.UIAxes3, 'Tiempo (s)');
            ylabel(app.UIAxes3, 'Amplitud (V)');


            % Calcula el espectrograma
            signal = app.OriginalSignal; % Convierte estéreo a mono

            window = hamming(1024); % Ventana de 512 puntos
            noverlap = 512; % Solapamiento entre ventanas
            nfft = 2048; % Número de puntos FFT
            [~, F, T, P] = spectrogram(signal, window, noverlap, nfft, app.fs);
            
            % Visualiza el espectrograma
            imagesc(app.UIAxes2, T, F, 10*log10(P)); % Muestra el espectrograma en dB
            colormap(app.UIAxes2);
            axis(app.UIAxes2, 'tight');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Frecuencia (Hz)');
            app.UIAxes2.YDir = "normal";
        end

        % Button pushed function: GenerarsealButton
        function Senal_Generada(app, event)
            signalType = app.SealDropDown.Value;
            amplitude = app.AmplitudEditField.Value;
            app.frecuencia = app.FcEditField.Value;
            fsample = app.FsEditField_3.Value;
            periodos = app.PeriodosEditField.Value;
            
            %Calcula la duracion total en funcion de los periodos y f
            T = 1/app.frecuencia;
            tTotal = periodos*T;
            tVector = 0:1/fsample:tTotal;

            %Generar la señal selecionas 
            switch signalType
                case 'Seno'
                    signal = amplitude * sin(2*pi*app.frecuencia * tVector);
                case 'Coseno'
                    signal = amplitude * cos(2*pi*app.frecuencia * tVector);
                case 'Cuadrada'
                    signal = amplitude * square(2*pi*app.frecuencia * tVector);
                case 'Triangular'
                    signal = amplitude * sawtooth(2*pi*app.frecuencia * tVector, 0.5);
                case 'Dientes'
                    signal = amplitude * sawtooth(2*pi*app.frecuencia * tVector);
                otherwise
                    uialert(app.UIFigure, 'Tipo de señal no reconocida')
                    return;
            end


            % Asignar señal generada a `app.audioData` y otras propiedades
            app.audioData = signal(:); % Asegurar que sea un vector columna
            app.fs = fsample;
            app.OriginalSignal = app.audioData; % Almacenar como señal original
            app.OriginalTime = tVector(:);      % Almacenar el tiempo original

            %Graficamos

            plot(app.UIAxes3, tVector, signal); 
            axis(app.UIAxes3, 'tight');
            title(app.UIAxes3, ['Señal: ', signalType])
            
            % Actualizar el espectrograma en UIAxes2_2
            if length(app.audioData) < 512
                uialert(app.UIFigure, 'La señal es demasiado corta para calcular el espectrograma. Intente con una señal más larga.', 'Advertencia');
                return;
            end
            window = hamming(1024);
            noverlap = 512;
            nfft = 2048;

            [~, F, T, P] = spectrogram(signal, window, noverlap, nfft, app.fs, 'yaxis');
            
            % Visualiza el espectrograma
            
            imagesc(app.UIAxes2, T, F, 10*log10(P)); % Muestra el espectrograma en dB
            colormap(app.UIAxes2);
            axis(app.UIAxes2, 'tight');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Frecuencia (Hz)');
            app.UIAxes2.YDir = "normal";

        end

        % Value changed function: InvertirsealCheckBox
        function Invertir_Senal(app, event)
            activado = app.InvertirsealCheckBox.Value;

            
            if activado
                stop(app.player);  % Detiene la reproducción
                % Invertir la señal en el eje de tiempos
                invertedTime = -flipud(app.OriginalTime); % Invertir y cambiar el signo del tiempo
                app.audioData = flipud(app.OriginalSignal); % Señal invertida en el dominio temporal

                % Graficar la señal invertida en UIAxes3
                plot(app.UIAxes3, invertedTime, app.OriginalSignal);
                title(app.UIAxes3, 'Señal Invertida (Dominio Temporal)');
                xlabel(app.UIAxes3, 'Tiempo (s)');
                ylabel(app.UIAxes3, 'Amplitud');
                axis(app.UIAxes3, 'tight');

                % Calcular el espectrograma invertido
                signal = flipud(app.OriginalSignal); % Señal invertida para espectrograma
                window = hamming(1024); % Ventana de hamming
                noverlap = 512; % Solapamiento entre ventanas
                nfft = 2048; % Número de puntos FFT
                [~, F, T, P] = spectrogram(signal, window, noverlap, nfft, app.fs);

                % Ajustar las frecuencias para la inversión
                F = flipud(F); % Invertir el eje de frecuencias
                T = -flipud(T(:)); % Invertir el eje de tiempos
                P = flipud(P); % Invertir las intensidades asociadas
        
                % Visualizar el espectrograma invertido en UIAxes2
                imagesc(app.UIAxes2, T, F, 10 * log10(P)); % Mostrar el espectrograma en dB
                colormap(app.UIAxes2);
                axis(app.UIAxes2, 'tight');
                xlabel(app.UIAxes2, 'Tiempo (s)');
                ylabel(app.UIAxes2, 'Frecuencia (Hz)');
                app.UIAxes2.YDir = 'normal'; % Mantener las frecuencias ascendentes

                % Reiniciar el reproductor creando un nuevo objeto audioplayer
                app.player = audioplayer(app.audioData, app.fs);

            else
                stop(app.player);  % Detiene la reproducción

                % Restaurar la señal original
                app.audioData = app.OriginalSignal;

                % Graficar la señal original en UIAxes3
                plot(app.UIAxes3, app.OriginalTime, app.OriginalSignal);
                title(app.UIAxes3, 'Señal Original (Dominio Temporal)');
                xlabel(app.UIAxes3, 'Tiempo (s)');
                ylabel(app.UIAxes3, 'Amplitud');
                axis(app.UIAxes3, 'tight');

                % Restaurar el espectrograma original en UIAxes2
                signal = app.OriginalSignal; % Señal original
                window = hamming(1024); % Ventana de 512 puntos
                noverlap = 512; % Solapamiento entre ventanas
                nfft = 2048; % Número de puntos FFT
                [~, F, T, P] = spectrogram(signal, window, noverlap, nfft, app.fs);

                % Visualizar el espectrograma original en UIAxes2
                imagesc(app.UIAxes2, T, F, 10 * log10(P)); % Mostrar el espectrograma en dB
                colormap(app.UIAxes2);
                axis(app.UIAxes2, 'tight');
                xlabel(app.UIAxes2, 'Tiempo (s)');
                ylabel(app.UIAxes2, 'Frecuencia (Hz)');
                app.UIAxes2.YDir = 'normal'; % Mantener las frecuencias ascendentes

                % Reiniciar el reproductor creando un nuevo objeto audioplayer
                app.player = audioplayer(app.audioData, app.fs);
            end
        end

        % Button pushed function: HISTOGRAMAButton
        function Histograma(app, event)
            % Verificar si hay un audio cargado
            if isempty(app.audioData)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado.', 'Error');
                return;
            end

            % Crear una nueva ventana para el histograma
            figHistograma = uifigure('Name', 'Histograma del Audio', 'Position', [100, 100, 600, 400]);
            
            % Crear el UIAxes en la nueva ventana
            axHistograma = uiaxes('Parent', figHistograma, 'Position', [50, 50, 500, 300]);
            
            % Parámetros de la FFT
            Fs = app.fs; % Frecuencia de muestreo (debe estar disponible en app)
            nfft = 1024; % Tamaño de la FFT
            L = length(app.audioData); % Longitud de la señal

            % Calcular la FFT de la señal
            fft_signal = fft(app.audioData, nfft); % FFT de la señal

            % Aplicar fftshift para centrar las frecuencias en torno a 0
            fft_shifted = fftshift(fft_signal); % Mueve las frecuencias negativas al centro
            frequencies = linspace(-Fs/2, Fs/2, nfft); % Eje de frecuencias con fftshift

            % Calcular la potencia
            P = abs(fft_shifted / L).^2; % Potencia normalizada


            % Graficar el espectro en dB
            plot(axHistograma, frequencies, P, 'LineWidth', 1.5);
            title(axHistograma, 'FFT');
            xlabel(axHistograma, 'Frecuencia (Hz)');
            ylabel(axHistograma, 'Potencia');
            grid(axHistograma, 'on');
        end

        % Value changed function: CompresinExpansionEditField
        function CompresionExpansion(app, event)
            % Verificar si hay una señal cargada
            if isempty(app.audioData)
                uialert(app.UIFigure, 'No hay señal cargada para modificar.', 'Error');
                return;
            end

            % El valor dek factor K
            K = app.CompresinExpansionEditField.Value;

            % Aplicar compresión o expansión temporal
            tOriginal = app.OriginalTime; % Tiempo original de la señal
            tNuevo = tOriginal * K;       % Escalado del tiempo (compresión/expansión)

            % Interpolación de la señal para ajustar al nuevo tiempo
            app.audioDataMod = interp1(tOriginal, app.OriginalSignal, tNuevo, 'linear', 0);

            % Graficar la señal modificada
            plot(app.UIAxes3_2, tNuevo, app.audioDataMod);
            title(app.UIAxes3_2, sprintf('Señal Modificada (K = %.2f)', K));
            xlabel(app.UIAxes3_2, 'Tiempo (s)');
            ylabel(app.UIAxes3_2, 'Amplitud');
            axis(app.UIAxes3_2, 'tight');

            % Actualizar el reproductor de audio
            app.player2 = audioplayer(app.audioDataMod, app.fs);

            signal = app.audioDataMod;
            window = hamming(1024); % Ventana de 512 puntos
            noverlap = 512; % Solapamiento entre ventanas
            nfft = 2048; % Número de puntos FFT
            [~, F, T, P] = spectrogram(signal, window, noverlap, nfft, app.fs);
            
            % Visualiza el espectrograma
            imagesc(app.UIAxes2_2, T, F, 10*log10(P)); % Muestra el espectrograma en dB
            colormap(app.UIAxes2_2);
            axis(app.UIAxes2_2, 'tight');
            xlabel(app.UIAxes2_2, 'Tiempo (s)');
            ylabel(app.UIAxes2_2, 'Frecuencia (Hz)');
            colormap(app.UIAxes2_2, 'jet');
            app.UIAxes2_2.YDir = "normal";
        end

        % Selection changed function: EfectosButtonGroup
        function Efectos(app, event)
            % Verificar si hay audio cargado o generado
            if isempty(app.audioData)
                uialert(app.UIFigure, 'No hay señal cargada o generada.', 'Error');
                return;
            end

            % Restaurar la señal original antes de aplicar un nuevo efecto
            app.audioDataMod = app.OriginalSignal;

            % Determinar el efecto seleccionado
            selectedEfecto = app.EfectosButtonGroup.SelectedObject.Text;

            % Aplicar el efecto seleccionado
            switch selectedEfecto
                case 'Eco'
                    % Crear un eco básico
                    delay = round(0.3 * app.fs); % Retardo de 300 ms
                    atten = 0.6; % Atenuación del eco
                    ecoSignal = [app.audioDataMod; zeros(delay, 1)]; % Extender señal con ceros
                    ecoSignal(delay+1:end) = ecoSignal(delay+1:end) + atten * app.audioDataMod;
                    app.audioDataMod = ecoSignal;
                case 'Distorsion'
                    % Distorsión mediante saturación
                    gain = 5; % Ganancia
                    app.audioDataMod = tanh(gain * app.audioDataMod); % Saturación con tangente hiperbólica
                case 'Reverberacion'
                    % Reverberación simulada con un filtro de convolución
                    reverbKernel = [1; zeros(2000, 1); 0.5; zeros(2000, 1); 0.25]; % Kernel de reverberación
                    app.audioDataMod = conv(app.audioDataMod, reverbKernel, 'same');
                case 'Amplificacion'
                    % Amplificación básica
                    app.audioDataMod = app.audioDataMod * 3; % Duplicar amplitud
                case 'Ninguno'
                    % Sin efecto (se mantiene la señal original)
                    app.audioDataMod = app.OriginalSignal;
                otherwise
                    % Si por alguna razón no se reconoce el efecto
                    app.audioDataMod = app.OriginalSignal;
            end
            
            % Crear un nuevo objeto audioplayer con la señal modificada
            app.player2 = audioplayer(app.audioDataMod, app.fs);

            % Graficar la señal con el efecto aplicado en UIAxes3_2
            t = (0:length(app.audioDataMod)-1) / app.fs;
            plot(app.UIAxes3_2, t, app.audioDataMod);
            axis(app.UIAxes3_2, 'tight');
            title(app.UIAxes3_2, ['Señal con ', selectedEfecto]);
            xlabel(app.UIAxes3_2, 'Tiempo (s)');
            ylabel(app.UIAxes3_2, 'Amplitud');

            % Actualizar el espectrograma en UIAxes2_2
            if length(app.audioDataMod) < 512
                uialert(app.UIFigure, 'La señal es demasiado corta para calcular el espectrograma. Intente con una señal más larga.', 'Advertencia');
                return;
            end
            window = hamming(1024);
            noverlap = 512;
            nfft = 2048;
            [~, F, T, P] = spectrogram(app.audioDataMod, window, noverlap, nfft, app.fs);
            imagesc(app.UIAxes2_2, T, F, 10*log10(P));
            colormap(app.UIAxes2_2, 'jet');
            axis(app.UIAxes2_2, 'tight');
            xlabel(app.UIAxes2_2, 'Tiempo (s)');
            ylabel(app.UIAxes2_2, 'Frecuencia (Hz)');
            app.UIAxes2_2.YDir = 'normal';
            
        end

        % Selection changed function: RuidoButtonGroup
        function Ruido(app, event)
            % Verificar si hay audio cargado o generado
            if isempty(app.audioData)
                uialert(app.UIFigure, 'No hay señal cargada o generada.', 'Error');
                return;
            end

            % Determinar el efecto seleccionado
            selectedRuido = app.RuidoButtonGroup.SelectedObject.Text;

            % Restaurar la señal original antes de agregar ruido
            app.audioDataMod = app.OriginalSignal;
            
             % Aplicar el efecto seleccionado
            switch selectedRuido
                case 'Ruido Blanco'
                    % Ruido blanco
                    ruido = 0.05 * randn(size(app.audioDataMod));
                case 'Ruido Uniforme'
                    % Ruido uniforme (rango -0.025 a 0.025)
                    ruido = 0.05 * (rand(size(app.audioDataMod)) - 0.5);
                case 'Ruido Impulsivo'
                    % Ruido impulsivo con picos aleatorios
                    ruido = zeros(size(app.audioDataMod));
                    numSpikes = round(length(app.audioDataMod) * 0.01); % 1% de la señal
                    spikeIndices = randperm(length(app.audioDataMod), numSpikes);
                    ruido(spikeIndices) = 0.5 * sign(randn(size(spikeIndices))); % Picos +/- 0.5
                case 'Ruido Sinusoidal'
                    % Ruido sinusoidal con frecuencia aleatoria
                    t = (0:length(app.audioDataMod)-1) / app.fs;
                    freq = 50 + 200 * rand; % Frecuencia entre 50 Hz y 250 Hz
                    ruido = 0.05 * sin(2 * pi * freq * t(:));
                case 'Ninguno'
                    % Sin ruido
                    ruido = zeros(size(app.audioDataMod));
                otherwise
                    % Sin ruido
                    ruido = zeros(size(app.audioDataMod));
            end

            % Agregar el ruido a la señal existente
            app.audioDataMod = app.audioDataMod + ruido;
            
            % Crear un nuevo objeto audioplayer con la señal modificada
            app.player2 = audioplayer(app.audioDataMod, app.fs);

            % Graficar la señal con ruido en UIAxes3_2
            t = (0:length(app.audioDataMod)-1) / app.fs;
            plot(app.UIAxes3_2, t, app.audioDataMod);
            axis(app.UIAxes3_2, 'tight');
            title(app.UIAxes3_2, ['Señal con ', selectedRuido]);
            xlabel(app.UIAxes3_2, 'Tiempo (s)');
            ylabel(app.UIAxes3_2, 'Amplitud');

            % Actualizar el espectrograma en UIAxes2_2
            if length(app.audioDataMod) < 512
                uialert(app.UIFigure, 'La señal es demasiado corta para calcular el espectrograma. Intente con una señal más larga.', 'Advertencia');
                return;
            end
            window = hamming(1024);
            noverlap = 512;
            nfft = 2048;
            [~, F, T, P] = spectrogram(app.audioDataMod, window, noverlap, nfft, app.fs);
            imagesc(app.UIAxes2_2, T, F, 10*log10(P));
            colormap(app.UIAxes2_2, 'jet');
            axis(app.UIAxes2_2, 'tight');
            xlabel(app.UIAxes2_2, 'Tiempo (s)');
            ylabel(app.UIAxes2_2, 'Frecuencia (Hz)');
            app.UIAxes2_2.YDir = 'normal';
        end

        % Button pushed function: VerenelpanelinferiorButton
        function CambiarPanel(app, event)
            % Verificar si hay señal cargada
            if isempty(app.audioData)
                uialert(app.UIFigure, 'No hay señal cargada o generada para duplicar.', 'Error');
                return;
            end

            % Obtener los datos del gráfico en UIAxes3 (señal temporal)
            lines = findobj(app.UIAxes3, 'Type', 'line');
            if isempty(lines)
                uialert(app.UIFigure, 'No hay datos de la señal temporal para duplicar.', 'Error');
                return;
            end

            app.audioDataMod=app.audioData;

            xData = get(lines, 'XData');
            yData = get(lines, 'YData');

            % Graficar en UIAxes3_2
            plot(app.UIAxes3_2, xData, yData);
            title(app.UIAxes3_2, 'Señal Temporal');
            xlabel(app.UIAxes3_2, 'Tiempo (s)');
            ylabel(app.UIAxes3_2, 'Amplitud');
            axis(app.UIAxes3_2, 'tight');

            % Obtener los datos del espectrograma en UIAxes2
            images = findobj(app.UIAxes2, 'Type', 'image');
            if isempty(images)
                uialert(app.UIFigure, 'No hay datos del espectrograma para duplicar.', 'Error');
                return;
            end

            CData = get(images, 'CData');
            XData = get(images, 'XData');
            YData = get(images, 'YData');

            % Graficar en UIAxes2_2
            imagesc(app.UIAxes2_2, XData, YData, CData);
            title(app.UIAxes2_2, 'Espectrograma');
            xlabel(app.UIAxes2_2, 'Tiempo (s)');
            ylabel(app.UIAxes2_2, 'Frecuencia (Hz)');
            colormap(app.UIAxes2_2, 'jet');
            axis(app.UIAxes2_2, 'tight');
            app.UIAxes2_2.YDir = 'normal';
        end

        % Button pushed function: Button_5
        function Reproducir2(app, event)
            %Callback del botón de reproducir
            % Verificar si hay un archivo de audio cargado
            if isempty(app.audioDataMod)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado para reproducir.', 'Error');
                return;
            end

            % Verificar si el objeto player ya existe y está pausado
            if ~isempty(app.player2) && isa(app.player2, 'audioplayer') && ~isplaying(app.player2)
                resume(app.player2);  % Retomar la reproducción desde donde se pausó
            else
                % Crear el reproductor y reproducir desde el inicio
                app.player2 = audioplayer(app.audioDataMod, app.fs);
                play(app.player2);
            end
        end

        % Button pushed function: Button_6
        function Pause2(app, event)
            %Callback del botón de pausa
            if isempty(app.audioDataMod)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado para reproducir.', 'Error');
                return;
            end
    
            if ~isempty(app.player2) && isplaying(app.player2)
                pause(app.player2);  % Pausar la reproducción
            end 
        end

        % Button pushed function: Button_8
        function Guardar_Archivo2(app, event)
            % Código para guardar un archivo de audio
            [filename, filepath] = uiputfile('*.wav', 'Audios');
            if isequal(filename, 0)
               disp('No se seleccionó ningún nombre para guardar el archivo');
            else
                fullFileName = fullfile(filepath, filename);
                audiowrite(fullFileName, app.audioDataMod, app.fs); % Guarda el archivo de audio
                disp('Archivo guardado exitosamente');
            end
        end

        % Button pushed function: Button_7
        function Stop2(app, event)
            %Callback del botón de parar
            if isempty(app.audioDataMod)
                uialert(app.UIFigure, 'No hay ningún archivo de audio cargado para reproducir.', 'Error');
                return;
            end

            if ~isempty(app.player2) && isplaying(app.player2)
                stop(app.player2);  % Detiene la reproducción
            end

            % Reiniciar el reproductor creando un nuevo objeto audioplayer
            app.player2 = audioplayer(app.audioDataMod, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SealCargadaPanel = uipanel(app.UIFigure);
            app.SealCargadaPanel.Title = 'Señal Cargada';
            app.UIAxes2 = uiaxes(app.SealCargadaPanel);
            title(app.UIAxes2, 'Espectrograma')
            xlabel(app.UIAxes2, 'Frecuencia')
            ylabel(app.UIAxes2, 'Amplitud')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'jet')
            app.UIAxes3 = uiaxes(app.SealCargadaPanel);
            title(app.UIAxes3, 'Dominio Temporal')
            xlabel(app.UIAxes3, 'Tiempo')
            ylabel(app.UIAxes3, 'Amplitud')
            zlabel(app.UIAxes3, 'Z')
            app.VerenelpanelinferiorButton = uibutton(app.SealCargadaPanel, 'push');
            app.VerenelpanelinferiorButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarPanel, true);
            app.VerenelpanelinferiorButton.Text = {'Ver en el '; 'panel inferior'};
            app.SealEditadaPanel = uipanel(app.UIFigure);
            app.SealEditadaPanel.Title = 'Señal Editada';
            app.UIAxes2_2 = uiaxes(app.SealEditadaPanel);
            title(app.UIAxes2_2, 'Espectograma')
            xlabel(app.UIAxes2_2, 'Tiempo')
            ylabel(app.UIAxes2_2, 'Frecuencia')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.SealEditadaPanel);
            title(app.UIAxes3_2, 'Dominio Temporal')
            xlabel(app.UIAxes3_2, 'Tiempo')
            ylabel(app.UIAxes3_2, 'Amplitud')
            zlabel(app.UIAxes3_2, 'Z')
            app.GrabacinPanel = uipanel(app.UIFigure);
            app.GrabacinPanel.Title = 'Grabación';
            app.GrabarButton = uibutton(app.GrabacinPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @Grabar, true);
            app.GrabarButton.Icon = fullfile(pathToMLAPP, 'simboloGrabar.png');
            app.GrabarButton.Text = 'Grabar';
            app.StopButton = uibutton(app.GrabacinPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Parar_Grabacion, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, '1-f0dda047.png');
            app.StopButton.Text = 'Stop';
            app.BitsDropDownLabel = uilabel(app.GrabacinPanel);
            app.BitsDropDownLabel.Text = 'Bits';
            app.BitsDropDown = uidropdown(app.GrabacinPanel);
            app.BitsDropDown.Items = {'8', '16', '24'};
            app.BitsDropDown.Value = '8';
            app.FsEditFieldLabel = uilabel(app.GrabacinPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.GrabacinPanel, 'numeric');
            app.FsEditField.Value = 44100;
            app.HzLabel = uilabel(app.GrabacinPanel);
            app.HzLabel.Text = 'Hz';
            app.ModificarsealPanel = uipanel(app.UIFigure);
            app.ModificarsealPanel.Title = 'Modificar señal';
            app.CompresinExpansionEditFieldLabel = uilabel(app.ModificarsealPanel);
            app.CompresinExpansionEditFieldLabel.Text = {'Compresión /'; 'Expansion'};
            app.CompresinExpansionEditField = uieditfield(app.ModificarsealPanel, 'numeric');
            app.CompresinExpansionEditField.ValueChangedFcn = createCallbackFcn(app, @CompresionExpansion, true);
            app.CompresinExpansionEditField.Value = 1;
            app.InvertirsealCheckBox = uicheckbox(app.ModificarsealPanel);
            app.InvertirsealCheckBox.ValueChangedFcn = createCallbackFcn(app, @Invertir_Senal, true);
            app.InvertirsealCheckBox.Text = 'Invertir señal';
            app.CargarSealPanel = uipanel(app.UIFigure);
            app.CargarSealPanel.Title = 'Cargar Señal';
            app.OpenFileButton = uibutton(app.CargarSealPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @Cargar_Archivo, true);
            app.OpenFileButton.Icon = fullfile(pathToMLAPP, 'simboloOpen.png');
            app.OpenFileButton.Text = 'Open File';
            app.NCanalesEditFieldLabel = uilabel(app.CargarSealPanel);
            app.NCanalesEditFieldLabel.Text = 'Nº Canales';
            app.NCanalesEditField = uieditfield(app.CargarSealPanel, 'numeric');
            app.NCanalesEditField.Limits = [0 Inf];
            app.FsEditField_2Label = uilabel(app.CargarSealPanel);
            app.FsEditField_2Label.Text = 'Fs';
            app.FsEditField_2 = uieditfield(app.CargarSealPanel, 'numeric');
            app.FsEditField_2.Limits = [0 Inf];
            app.nmuestrasEditFieldLabel = uilabel(app.CargarSealPanel);
            app.nmuestrasEditFieldLabel.Text = 'nº muestras';
            app.nmuestrasEditField = uieditfield(app.CargarSealPanel, 'numeric');
            app.nmuestrasEditField.Limits = [0 Inf];
            app.PotenciaEditFieldLabel = uilabel(app.CargarSealPanel);
            app.PotenciaEditFieldLabel.Text = 'Potencia';
            app.PotenciaEditField = uieditfield(app.CargarSealPanel, 'numeric');
            app.PotenciaEditField.Limits = [0 Inf];
            app.PotsonoraEditFieldLabel = uilabel(app.CargarSealPanel);
            app.PotsonoraEditFieldLabel.Text = 'Pot sonora';
            app.PotsonoraEditField = uieditfield(app.CargarSealPanel, 'numeric');
            app.PotsonoraEditField.Limits = [0 Inf];
            app.DuracionEditFieldLabel = uilabel(app.CargarSealPanel);
            app.DuracionEditFieldLabel.Text = 'Duracion';
            app.DuracionEditField = uieditfield(app.CargarSealPanel, 'numeric');
            app.DuracionEditField.Limits = [0 Inf];
            app.CompresinEditFieldLabel = uilabel(app.CargarSealPanel);
            app.CompresinEditFieldLabel.Text = 'Compresión';
            app.CompresinEditField = uieditfield(app.CargarSealPanel, 'text');
            app.CompresinEditField.Value = '..............';
            app.GeneralsealsimplePanel = uipanel(app.UIFigure);
            app.GeneralsealsimplePanel.Title = 'General señal simple';
            app.SealDropDownLabel = uilabel(app.GeneralsealsimplePanel);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.GeneralsealsimplePanel);
            app.SealDropDown.Items = {'Seno', 'Coseno', 'Cuadrada', 'Triangular', 'Dientes'};
            app.SealDropDown.Value = 'Seno';
            app.FcEditFieldLabel = uilabel(app.GeneralsealsimplePanel);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.GeneralsealsimplePanel, 'numeric');
            app.FsEditField_3Label = uilabel(app.GeneralsealsimplePanel);
            app.FsEditField_3Label.Text = 'Fs';
            app.FsEditField_3 = uieditfield(app.GeneralsealsimplePanel, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.GeneralsealsimplePanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GeneralsealsimplePanel, 'numeric');
            app.PeriodosEditFieldLabel = uilabel(app.GeneralsealsimplePanel);
            app.PeriodosEditFieldLabel.Text = 'Periodos';
            app.PeriodosEditField = uieditfield(app.GeneralsealsimplePanel, 'numeric');
            app.GenerarsealButton = uibutton(app.GeneralsealsimplePanel, 'push');
            app.GenerarsealButton.ButtonPushedFcn = createCallbackFcn(app, @Senal_Generada, true);
            app.GenerarsealButton.Icon = fullfile(pathToMLAPP, 'Generar.png');
            app.GenerarsealButton.Text = 'Generar señal';
            app.HISTOGRAMAButton = uibutton(app.UIFigure, 'push');
            app.HISTOGRAMAButton.ButtonPushedFcn = createCallbackFcn(app, @Histograma, true);
            app.HISTOGRAMAButton.Text = 'HISTOGRAMA';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.jpg');
            app.RuidoButtonGroup = uibuttongroup(app.UIFigure);
            app.RuidoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Ruido, true);
            app.RuidoButtonGroup.Title = 'Ruido';
            app.RuidoBlancoButton = uiradiobutton(app.RuidoButtonGroup);
            app.RuidoBlancoButton.Text = 'Ruido Blanco';
            app.RuidoUniformeButton = uiradiobutton(app.RuidoButtonGroup);
            app.RuidoUniformeButton.Text = 'Ruido Uniforme';
            app.RuidoImpulsivoButton = uiradiobutton(app.RuidoButtonGroup);
            app.RuidoImpulsivoButton.Text = 'Ruido Impulsivo';
            app.RuidoSinusoidalButton = uiradiobutton(app.RuidoButtonGroup);
            app.RuidoSinusoidalButton.Text = 'Ruido Sinusoidal';
            app.NingunoButton = uiradiobutton(app.RuidoButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.NingunoButton.Value = true;
            app.EfectosButtonGroup = uibuttongroup(app.UIFigure);
            app.EfectosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Efectos, true);
            app.EfectosButtonGroup.Title = 'Efectos';
            app.EcoButton = uiradiobutton(app.EfectosButtonGroup);
            app.EcoButton.Text = 'Eco';
            app.DistorsionButton = uiradiobutton(app.EfectosButtonGroup);
            app.DistorsionButton.Text = 'Distorsion';
            app.ReverberacionButton = uiradiobutton(app.EfectosButtonGroup);
            app.ReverberacionButton.Text = 'Reverberacion';
            app.AmplificacionButton = uiradiobutton(app.EfectosButtonGroup);
            app.AmplificacionButton.Text = 'Amplificacion';
            app.NingunoButton_2 = uiradiobutton(app.EfectosButtonGroup);
            app.NingunoButton_2.Text = 'Ninguno';
            app.NingunoButton_2.Value = true;
            app.ControladorespanelsuperiorPanel = uipanel(app.UIFigure);
            app.ControladorespanelsuperiorPanel.Title = 'Controladores panel superior';
            app.Button_2 = uibutton(app.ControladorespanelsuperiorPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Reproducir, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'simboloPlay.png');
            app.Button_2.Text = '';
            app.Button = uibutton(app.ControladorespanelsuperiorPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Pausa, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'simboloPause.png');
            app.Button.Text = '';
            app.Button_4 = uibutton(app.ControladorespanelsuperiorPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'simboloStop.png');
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.ControladorespanelsuperiorPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Guardar_Archivo, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'simboloSave.png');
            app.Button_3.Text = '';
            app.ControladorespanelinferiorPanel = uipanel(app.UIFigure);
            app.ControladorespanelinferiorPanel.Title = 'Controladores panel inferior';
            app.Button_5 = uibutton(app.ControladorespanelinferiorPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Reproducir2, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'simboloPlay.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.ControladorespanelinferiorPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Pause2, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'simboloPause.png');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.ControladorespanelinferiorPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Stop2, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'simboloStop.png');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.ControladorespanelinferiorPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Guardar_Archivo2, true);
            app.Button_8.Icon = fullfile(pathToMLAPP, 'simboloSave.png');
            app.Button_8.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end